classdef Algorithm_Bulk_Tester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mRangeButton                    matlab.ui.control.StateButton
        StepIncrementButtonGroup        matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.ToggleButton
        Button_2                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        RunCoverageTest                 matlab.ui.control.Button
        HideImagesButton                matlab.ui.control.StateButton
        CameraBaseline                  matlab.ui.control.Spinner
        CameraRespxSpinnerLabel_2       matlab.ui.control.Label
        CameraLocationLabel             matlab.ui.control.Label
        camZSpinner                     matlab.ui.control.Spinner
        ZmSpinnerLabel                  matlab.ui.control.Label
        camYSpinner                     matlab.ui.control.Spinner
        YmSpinnerLabel                  matlab.ui.control.Label
        camXSpinner                     matlab.ui.control.Spinner
        XmSpinnerLabel                  matlab.ui.control.Label
        CompleteLamp                    matlab.ui.control.Lamp
        CompleteLampLabel               matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        RunAllSelectedButton            matlab.ui.control.Button
        RunSelectionButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        AquaTrackAlgorithmBulkTestCaseAppLabel  matlab.ui.control.Label
        PythonGreenContourResultsLabel  matlab.ui.control.Label
        PythonSobelCircleFilterResultsLabel  matlab.ui.control.Label
        MatlabSobelCircleFilterResultsLabel  matlab.ui.control.Label
        ActualPositionfromBlenderLabel  matlab.ui.control.Label
        UITableCalcPyCon                matlab.ui.control.Table
        UITableRightPyCon               matlab.ui.control.Table
        UITableLeftPyCon                matlab.ui.control.Table
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        DataFileSpinner                 matlab.ui.control.Spinner
        DataFileSpinnerLabel            matlab.ui.control.Label
        CameraRespxSpinner              matlab.ui.control.Spinner
        CameraRespxSpinnerLabel         matlab.ui.control.Label
        UITableExpected                 matlab.ui.control.Table
        UITableClacLite                 matlab.ui.control.Table
        UITableRightLite                matlab.ui.control.Table
        UITableLeftLite                 matlab.ui.control.Table
        UITablePySobel                  matlab.ui.control.Table
        UITableRightPySobel             matlab.ui.control.Table
        UITableLeftPySobel              matlab.ui.control.Table
        UIAxesZError3                   matlab.ui.control.UIAxes
        UIAxesZError2                   matlab.ui.control.UIAxes
        UIAxesZError5                   matlab.ui.control.UIAxes
        UIAxesZError4                   matlab.ui.control.UIAxes
        UIAxesZError1                   matlab.ui.control.UIAxes
        UIAxesYError3                   matlab.ui.control.UIAxes
        UIAxesYError2                   matlab.ui.control.UIAxes
        UIAxesYError5                   matlab.ui.control.UIAxes
        UIAxesYError4                   matlab.ui.control.UIAxes
        UIAxesYError1                   matlab.ui.control.UIAxes
        UIAxesLeftLite                  matlab.ui.control.UIAxes
        UIAxesXError3                   matlab.ui.control.UIAxes
        UIAxesXError2                   matlab.ui.control.UIAxes
        UIAxesXError5                   matlab.ui.control.UIAxes
        UIAxesXError4                   matlab.ui.control.UIAxes
        UIAxesXError1                   matlab.ui.control.UIAxes
        UIAxesRightPyContour            matlab.ui.control.UIAxes
        UIAxesLeftPyContour             matlab.ui.control.UIAxes
        UIAxesRightOriginal             matlab.ui.control.UIAxes
        UIAxesLeftOriginal              matlab.ui.control.UIAxes
        UIAxesRightPySobel              matlab.ui.control.UIAxes
        UIAxesLeftPySobel               matlab.ui.control.UIAxes
        UIAxesRightLite                 matlab.ui.control.UIAxes
    end

 
%Algorith Bulk Tester TODO
%
% Add more parameterization 
%    (All camera components, image plots on or off, sample rate, etc)
%
% Ask teammate to double check calculatios and formulas
%
% Make how Python receives images cleaner
%
% Adjust algorithms if needed based on current and after checked
% performance


    properties (Access = private)
        %Camera Parameters
        width
        height
        camX
        camY
        camZ
        baseline

        %Toogle for whether to show images
        showImg

        %Image Organization
        all_images

        %Active Images
        imageLeft  
        imageRight 

        %Coverage Test Parameters
        courtCov
        isDetectedFlag
        isDetectedPrev
        covX
        covY
        covZ
        rangeOne

        %Blender Server Client, needed for seperation of bulk and non-bulk
        %functions
        client


        %Which file type and which number is selected
        serve_num
        fileType

        %Active X, Y, and Z values for Error scatter plots
        x_pres
        y_pres
        z_pres

        x_dat
        y_dat
        z_dat
        x_true
        y_true
        z_true

        %Plot variables that let the case statement only exist once
        UIAxes_presX
        UIAxes_presY
        UIAxes_presZ

        %Frame loop value
        sampledServeCounter
        repeatFlag
    end
    
    methods (Access = public)

        % image_gen generates all of the images and calls the algorithms.
        % It MUST be called withing serve_run or serve_bulk, as it only
        % makes the requests to Blender, not the initial connection. This
        % was done so the bulk function would not pause each time a file is
        % complete

        function court_coverage_test(app)
           %app.showImg = 1;
           app.courtCov = 1;

           app.isDetectedFlag = 0;
           app.isDetectedPrev = 0;

           %Clearing Images
           app.all_images = [];

           %Clearing Plots
           cla(app.UIAxesXError1);
           cla(app.UIAxesYError1);
           cla(app.UIAxesZError1);
           cla(app.UIAxesXError2);
           cla(app.UIAxesYError2);
           cla(app.UIAxesZError2);
           cla(app.UIAxesXError3);
           cla(app.UIAxesYError3);
           cla(app.UIAxesZError3);
           cla(app.UIAxesXError4);
           cla(app.UIAxesYError4);
           cla(app.UIAxesZError4);
           cla(app.UIAxesXError5);
           cla(app.UIAxesYError5);
           cla(app.UIAxesZError5);

           cla(app.UIAxesLeftPyContour);
           cla(app.UIAxesRightPyContour);
           cla(app.UIAxesLeftLite);
           cla(app.UIAxesRightPySobel);
           cla(app.UIAxesRightLite);
           cla(app.UIAxesLeftPySobel);

           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever

           app.client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");

           %Initializing Camera Parameters 
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end

           hold(app.UIAxesLeftPyContour, 'on');
           hold(app.UIAxesRightPyContour, 'on')
           hold(app.UIAxesLeftLite, 'on');
           hold(app.UIAxesRightLite, 'on');
           hold(app.UIAxesLeftPySobel, 'on');
           hold(app.UIAxesRightPySobel, 'on');
           app.UIAxes_presX = app.UIAxesXError1;
           app.UIAxes_presY = app.UIAxesYError1;
           app.UIAxes_presZ = app.UIAxesZError1;
           hold(app.UIAxes_presX, "on");
           hold(app.UIAxes_presY, "on");
           hold(app.UIAxes_presZ, "on");
           if app.rangeOne == 1
              ylim(app.UIAxes_presX, [-5 5]);
              ylim(app.UIAxes_presY, [-5 5]);
              ylim(app.UIAxes_presZ, [-5 5]);
           end
           app.sampledServeCounter = 0;
           for x_cc = 0:1:21
                for y_cc = 0:1:11

                   app.x_pres = (x_cc - 10.5);
                   app.y_pres = (y_cc - 5.5);
                   app.z_pres = 0.5;
                   T = table(app.x_pres, app.y_pres, 0.5);
                   app.UITableExpected.Data = T;
                   app.sampledServeCounter = app.sampledServeCounter + 1;

                   blenderLink(app.client, app.width,app.height,app.x_pres, app.y_pres, app.z_pres,0,0,0,"tennisBall");
                   %algorithm here
                   
                   %Go from real to pixel, where real is the edge of mono frame
                   app.imageLeft = blenderLink(app.client, app.width, app.height, (app.camX - (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
                   imagesc(app.UIAxesLeftOriginal, app.imageLeft, [0, 255]) % Apply fixed intensity range
                   %Generate the Right Image
                   app.imageRight = blenderLink(app.client, app.width, app.height, (app.camX + (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
                   imagesc(app.UIAxesRightOriginal, app.imageRight, [0, 255]) % Apply fixed intensity range


                   if(x_cc == 0) && (y_cc == 0)
                       imagesc(app.UIAxesLeftPyContour, app.imageLeft, [0, 255]) 
                       imagesc(app.UIAxesLeftPySobel, app.imageLeft, [0, 255])
                       imagesc(app.UIAxesLeftLite, app.imageLeft, [0, 255])
                   
                       imagesc(app.UIAxesRightPyContour, app.imageRight, [0, 255]) 
                       imagesc(app.UIAxesRightPySobel, app.imageRight, [0, 255])
                       imagesc(app.UIAxesRightLite, app.imageRight, [0, 255])
                   end

                   imwrite(app.imageLeft, "blender_L_im_.jpg");
                   imwrite(app.imageRight, "blender_R_im_.jpg");
       
                   algorithm_lite(app);
                   algorithm_py_sobel(app);
                   algorithm_py_contour(app);

                   app.isDetectedPrev = app.isDetectedFlag;
   
                end
            end
            hold(app.UIAxesLeftPyContour, 'off');
            hold(app.UIAxesRightPyContour, 'off');
            hold(app.UIAxesLeftLite, 'off');
            hold(app.UIAxesRightLite, 'off');
            hold(app.UIAxesLeftPySobel, 'off');
            hold(app.UIAxesRightPySobel, 'off');
            app.courtCov = 0;
            app.showImg = 0;
        end

        function image_gen(app)
           %Reads selected/active .dat file
           serve = readmatrix("/datFiles/"+ app.fileType + num2str(app.serve_num) + ".dat");
           dataSize = size(serve);
           numFrames = dataSize(1);
           app.sampledServeCounter = 1;
           framesTimeMs = 50;
           
           %Importing serve data. This is front loaded to save memory
           %allocation time, as well as the check to break early once the
           %simulation has ended
           x = serve(:,1);
           y = serve(:,3);
           z = serve(:,2);
           %For the number of frames the device will see, generate images

           %Image Management code by August
           for counter = 1:framesTimeMs:numFrames 
               %Move the Tennis Ball. Due to how the files are formated, x
               %and y are swapped. See the professor's original code on myCourses for
               %clear example: serveBall.m
               blenderLink(app.client,app.width,app.height,y(counter),x(counter),z(counter),0,0,0,"tennisBall");
               %Generate the Left Image
               app.imageLeft = blenderLink(app.client, app.width, app.height, (app.camX - (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
               if (app.showImg == 0)
                  imagesc(app.UIAxesLeftOriginal, app.imageLeft, [0, 255]) % Apply fixed intensity range
               end
               %Generate the Right Image
               app.imageRight = blenderLink(app.client, app.width, app.height, (app.camX + (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
               if (app.showImg == 0)
                  imagesc(app.UIAxesRightOriginal, app.imageRight, [0, 255]) % Apply fixed intensity range
               end
               app.all_images = [app.all_images app.imageRight app.imageLeft]; %creates array of all images, comment out if you dont use
           
               %Display expected values from Blender
               T = table(y(counter), x(counter), z(counter));
               app.UITableExpected.Data = T;
           
               imwrite(app.imageLeft, "blender_L_im_.jpg");
               imwrite(app.imageRight, "blender_R_im_.jpg");
           
               %Updates current X,Y,Z values so functions can see them
               app.x_pres = y(counter);
               app.y_pres = x(counter);
               app.z_pres = z(counter);

               app.x_true = [app.x_true app.x_pres];
               app.y_true = [app.y_true app.y_pres]; 
               app.z_true = [app.z_true app.z_pres]; 


               % Case statement decides which graph to plot on   
               switch app.serve_num
                  case 1
                     app.UIAxes_presX = app.UIAxesXError1;
                     app.UIAxes_presY = app.UIAxesYError1;
                     app.UIAxes_presZ = app.UIAxesZError1;
                  case 2
                     app.UIAxes_presX = app.UIAxesXError2;
                     app.UIAxes_presY = app.UIAxesYError2;
                     app.UIAxes_presZ = app.UIAxesZError2;
                  case 3
                     app.UIAxes_presX = app.UIAxesXError3;
                     app.UIAxes_presY = app.UIAxesYError3;
                     app.UIAxes_presZ = app.UIAxesZError3;
                  case 4
                     app.UIAxes_presX = app.UIAxesXError4;
                     app.UIAxes_presY = app.UIAxesYError4;
                     app.UIAxes_presZ = app.UIAxesZError4;
                  case 5
                     app.UIAxes_presX = app.UIAxesXError5;
                     app.UIAxes_presY = app.UIAxesYError5;
                     app.UIAxes_presZ = app.UIAxesZError5;
                  case others
                     app.UIAxes_presX = app.UIAxesXError1;
                     app.UIAxes_presY = app.UIAxesYError1;
                     app.UIAxes_presZ = app.UIAxesZError1;
              end

              disp(app.x_pres)

              if ((app.x_pres > 10) | (-10 > app.x_pres)) | ((app.y_pres > 6) |  (app.y_pres < -6))
                  pgon = polyshape([(-0.5 + app.sampledServeCounter)  (-0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter) ],[-10 10 10 -10]);
                  pg = plot(app.UIAxes_presX, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
                  pg = plot(app.UIAxes_presY, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
                  pg = plot(app.UIAxes_presZ, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
              end

              if app.rangeOne == 1
                 ylim(app.UIAxes_presX, [-5 5]);
                 ylim(app.UIAxes_presY, [-5 5]);
                 ylim(app.UIAxes_presZ, [-5 5]);
              end
              %Algorithms are called here
              algorithm_lite(app);
              algorithm_py_sobel(app)
              algorithm_py_contour(app);

              %Updates number of times loop has occured, used for plots
              if(app.repeatFlag == 0)
                 
                       %Move the Tennis Ball. Due to how the files are formated, x
                       %and y are swapped. See the professor's original code on myCourses for
                       %clear example: serveBall.m
                       blenderLink(app.client,app.width,app.height,y(counter),x(counter),z(counter),0,0,0,"tennisBall");
                       %Generate the Left Image
                       app.imageLeft = blenderLink(app.client, app.width, app.height, (app.camX - (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
                       if (app.showImg == 0)
                          imagesc(app.UIAxesLeftOriginal, app.imageLeft, [0, 255]) % Apply fixed intensity range
                       end
                       %Generate the Right Image
                       app.imageRight = blenderLink(app.client, app.width, app.height, (app.camX + (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
                       if (app.showImg == 0)
                          imagesc(app.UIAxesRightOriginal, app.imageRight, [0, 255]) % Apply fixed intensity range
                       end
                       app.all_images = [app.all_images app.imageRight app.imageLeft]; %creates array of all images, comment out if you dont use
                   
                       %Display expected values from Blender
                       T = table(y(counter), x(counter), z(counter));
                       app.UITableExpected.Data = T;
                   
                       %TODO: IMPROVE HOW PYTHON RECIEVES IMAGES
                       %Easiest solution was to save only the current file to pass
                       %to python. This is of course not ideal. Get Matlab to
                       %Python Array transfers working
                       imwrite(app.imageLeft, "blender_L_im_.jpg");
                       imwrite(app.imageRight, "blender_R_im_.jpg");
                   
                       %Updates current X,Y,Z values so functions can see them
                       app.x_pres = y(counter);
                       app.y_pres = x(counter);
                       app.z_pres = z(counter);

                       app.x_true = [app.x_true app.x_pres];
                       app.y_true = [app.y_true app.y_pres]; 
                       app.z_true = [app.z_true app.z_pres]; 
        
                       % Case statement decides which graph to plot on   
                       switch app.serve_num
                          case 1
                             app.UIAxes_presX = app.UIAxesXError1;
                             app.UIAxes_presY = app.UIAxesYError1;
                             app.UIAxes_presZ = app.UIAxesZError1;
                          case 2
                             app.UIAxes_presX = app.UIAxesXError2;
                             app.UIAxes_presY = app.UIAxesYError2;
                             app.UIAxes_presZ = app.UIAxesZError2;
                          case 3
                             app.UIAxes_presX = app.UIAxesXError3;
                             app.UIAxes_presY = app.UIAxesYError3;
                             app.UIAxes_presZ = app.UIAxesZError3;
                          case 4
                             app.UIAxes_presX = app.UIAxesXError4;
                             app.UIAxes_presY = app.UIAxesYError4;
                             app.UIAxes_presZ = app.UIAxesZError4;
                          case 5
                             app.UIAxes_presX = app.UIAxesXError5;
                             app.UIAxes_presY = app.UIAxesYError5;
                             app.UIAxes_presZ = app.UIAxesZError5;
                          case others
                             app.UIAxes_presX = app.UIAxesXError1;
                             app.UIAxes_presY = app.UIAxesYError1;
                             app.UIAxes_presZ = app.UIAxesZError1;
                      end
        
                      disp(app.x_pres)
        
                      if ((app.x_pres > 10) | (-10 > app.x_pres)) | ((app.y_pres > 6) |  (app.y_pres < -6))
                          pgon = polyshape([(-0.5 + app.sampledServeCounter)  (-0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter) ],[-10 10 10 -10]);
                          pg = plot(app.UIAxes_presX, pgon);
                          pg.FaceColor = "red";
                          pg.EdgeColor = "red";
                          pg = plot(app.UIAxes_presY, pgon);
                          pg.FaceColor = "red";
                          pg.EdgeColor = "red";
                          pg = plot(app.UIAxes_presZ, pgon);
                          pg.FaceColor = "red";
                          pg.EdgeColor = "red";
                      end
        
                      if app.rangeOne == 1
                         ylim(app.UIAxes_presX, [-5 5]);
                         ylim(app.UIAxes_presY, [-5 5]);
                         ylim(app.UIAxes_presZ, [-5 5]);
                      end
                      %Algorithms are called here
                      algorithm_lite(app);
                      algorithm_py_sobel(app)
                      algorithm_py_contour(app);
                 

                 app.repeatFlag = 1;
              else
                  app.sampledServeCounter = app.sampledServeCounter + 1;
              end

              %Break condition. This is used as the data files will
              %sometimes have excess post-movement data that is not needed
              %for simulation
              if ((x(counter) == x(numFrames)) && (y(counter) == y(numFrames)))
                  break
              end
           end
        end

        %Top Level Function for calling a single file test. This function
        %establishes the Blender-Matlab link, clears plots if used
        %previously, and sets any changed parameters
        function serve_run(app)

           %Clearing Images
           app.all_images = [];

           %Clearing Plots
           cla(app.UIAxesXError1);
           cla(app.UIAxesYError1);
           cla(app.UIAxesZError1);
           cla(app.UIAxesXError2);
           cla(app.UIAxesYError2);
           cla(app.UIAxesZError2);
           cla(app.UIAxesXError3);
           cla(app.UIAxesYError3);
           cla(app.UIAxesZError3);
           cla(app.UIAxesXError4);
           cla(app.UIAxesYError4);
           cla(app.UIAxesZError4);
           cla(app.UIAxesXError5);
           cla(app.UIAxesYError5);
           cla(app.UIAxesZError5);
           cla(app.UIAxesLeftPyContour);
           cla(app.UIAxesRightPyContour);
           cla(app.UIAxesLeftLite);
           cla(app.UIAxesRightPySobel);
           cla(app.UIAxesRightLite);
           cla(app.UIAxesLeftPySobel);

           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever

           app.client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");

           %Initializing Camera Parameters 
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end
            
           %Image generation call
           image_gen(app)

        end 
        
        %Bulk function. Does what serve_run does, but for all 5 files of a
        %serve or volley. Origianlly wanted an all at once function as
        %well, but not enough space for plots, unless a no-image variation
        %is made. Could probably be optimized as a if condition in
        %serve_run
        function serve_bluk(app)

           cla(app.UIAxesXError1);
           cla(app.UIAxesYError1);
           cla(app.UIAxesZError1);
           cla(app.UIAxesXError2);
           cla(app.UIAxesYError2);
           cla(app.UIAxesZError2);
           cla(app.UIAxesXError3);
           cla(app.UIAxesYError3);
           cla(app.UIAxesZError3);
           cla(app.UIAxesXError4);
           cla(app.UIAxesYError4);
           cla(app.UIAxesZError4);
           cla(app.UIAxesXError5);
           cla(app.UIAxesYError5);
           cla(app.UIAxesZError5);
           cla(app.UIAxesLeftPyContour);
           cla(app.UIAxesRightPyContour);
           cla(app.UIAxesLeftLite);
           cla(app.UIAxesRightPySobel);
           cla(app.UIAxesRightLite);
           cla(app.UIAxesLeftPySobel);

           app.all_images = [];
           app.x_dat = [];
           app.y_dat = [];
           app.z_dat = [];

           app.x_true = [];
           app.y_true = [];
           app.z_true = [];

           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever

           app.client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");
            
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end

           app.serve_num = 1;

           for i = 1:5  %currently runs only serves or volleys but can be modded

               app.repeatFlag = 0;
               image_gen(app)
               app.serve_num = app.serve_num +1;

               data_export = [app.x_true' app.y_true' app.z_true' app.x_dat' app.y_dat' app.z_dat'];
               writematrix(data_export,(app.fileType + "_" + i + "_buk_tester" + ".dat"),'Delimiter',' ');

               app.x_true = [];
               app.y_true = []; 
               app.z_true = []; 

               app.x_dat = [];
               app.y_dat = []; 
               app.z_dat = []; 
           end

        end 
        
        %algorithm_lite implements the grayscale-sobel-circles algorithm in
        %Matlab. Algorithm code by Marios.
        function algorithm_lite(app)

           % Convert to Grayscale
           grayImage_L = rgb2gray(app.imageLeft);
           grayImage_R = rgb2gray(app.imageRight);
                  
           % Edge Detection
           edgeImage_L = edge(grayImage_L, 'sobel');
           edgeImage_R = edge(grayImage_R, 'sobel');
                  
           % Circle Detection
           [centers_L, radii_L] = imfindcircles(edgeImage_L, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.88);
           [centers_R, radii_R] = imfindcircles(edgeImage_R, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.92);


           % 7. Extract Centroids
           if ~isempty(centers_L)
               xLeft = centers_L(1,1);
               yLeft = centers_L(1,2);
               T = table(centers_L(:,1), centers_L(:,2));
               app.UITableLeftLite.Data = T;
           else
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftLite.Data = T;
           end
       
           if ~isempty(centers_R)
               xRight = centers_R(1,1);
               yRight = centers_R(1,2);
               T = table(centers_R(:,1), centers_R(:,2));
               app.UITableRightLite.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightLite.Data = T;
           end
           % 8. Display Results
           if (app.showImg == 0)
               imagesc(app.UIAxesLeftLite, grayImage_L, [0, 255]); 
               colormap(app.UIAxesLeftLite, 'gray');
               hold(app.UIAxesLeftLite, 'on');
               if ~isempty(centers_L)
                   viscircles(app.UIAxesLeftLite, centers_L, radii_L, 'EdgeColor', 'r');
                   plot(app.UIAxesLeftLite, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesLeftLite, 'off');
               
               imagesc(app.UIAxesRightLite, grayImage_R, [0, 255]); 
               colormap(app.UIAxesRightLite, 'gray');
               hold(app.UIAxesRightLite, 'on');
               if ~isempty(centers_R)
                   viscircles(app.UIAxesRightLite, centers_R, radii_R, 'EdgeColor', 'r');
                   plot(app.UIAxesRightLite, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesRightLite, 'off');
           elseif (app.courtCov == 1)
               if ~isempty(centers_L) && ~isempty(centers_R)
                  plot(app.UIAxesLeftLite, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2); 
                  plot(app.UIAxesRightLite, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
           end


          % 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if ~isnan(xLeft) && ~isnan(xRight)
              % Baseline, focal length, and pixel size
              b = app.baseline * 1000;      % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = app.width / 2;  
              cyLeft = app.height / 2;  
              cxRight = app.width  / 2;  
              cyRight = app.height / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (-1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = app.camZ - Z;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITableClacLite.Data = T;
              scatter(app.UIAxes_presX, app.sampledServeCounter,(X - app.x_pres),5, "magenta");
              hold(app.UIAxes_presX, "on");
              scatter(app.UIAxes_presY, app.sampledServeCounter,(Y - app.y_pres),5, "magenta");
              hold(app.UIAxes_presY, "on");
              scatter(app.UIAxes_presZ, app.sampledServeCounter,(Z1 - app.z_pres),5, "magenta");
              hold(app.UIAxes_presZ, "on");
          else
              T = table();
              app.UITableClacLite.Data = T;
          end
        end


        %algorithm_lite implements the green channel contour algorithm in
        %Python. Algorithm code by Ethan.
        function algorithm_py_contour(app)
           mdatL = pyrunfile("ball_tracking_img.py --image blender_L_im_.jpg", "center_rad");
           mdatR = pyrunfile("ball_tracking_img.py --image blender_R_im_.jpg", "center_rad");
            
          %Conversion from py arrays to Matlab arrays
           mdatL = cellfun(@double, cell(mdatL));
           mdatR = cellfun(@double, cell(mdatR));
           % 7. Extract Centroid
           if mdatL  == [-100, -100, -100]
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
               disp("hello")
               mdatL = zeros(0,0,0);
               
           elseif ~isempty(mdatL)
               xLeft = mdatL(1);
               yLeft = mdatL(2);
               centers_L = [mdatL(1), mdatL(2)];
               T = table(mdatL(1), mdatL(2));
               app.UITableLeftPyCon.Data = T;
           else
               disp(mdatL(1))
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
           end

           if mdatR == [-100, -100, -100]
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
               mdatR = zeros(0,0,0);

           elseif ~isempty(mdatR)
               xRight = mdatR(1);
               yRight = mdatR(2);
               centers_R = [mdatR(1), mdatR(2)];
               T = table(mdatR(1), mdatR(2));
               app.UITableRightPyCon.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightPyCon.Data = T;
           end
       
           %% 8. Display Results
           if (app.showImg == 0)
               imagesc(app.UIAxesLeftPyContour, app.imageLeft, [0, 255]); 
               colormap(app.UIAxesLeftPyContour, 'gray');
               hold(app.UIAxesLeftPyContour, 'on');
               if ~isempty(mdatL)
                   viscircles(app.UIAxesLeftPyContour, centers_L, mdatL(3), 'EdgeColor', 'r');
                   plot(app.UIAxesLeftPyContour, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesLeftPyContour, 'off');
               
               imagesc(app.UIAxesRightPyContour, app.imageRight, [0, 255]); 
               colormap(app.UIAxesRightPyContour, 'gray');
               hold(app.UIAxesRightPyContour, 'on');
               if ~isempty(mdatR)
                   viscircles(app.UIAxesRightPyContour, centers_R, mdatR(3), 'EdgeColor', 'r');
                   plot(app.UIAxesRightPyContour, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesRightPyContour, 'off');

           elseif (app.courtCov == 1)
               if ~isempty(mdatL) && ~isempty(mdatR)
                  plot(app.UIAxesLeftPyContour, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
                  plot(app.UIAxesRightPyContour, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
           end


          % 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if ~isnan(xLeft) && ~isnan(xRight)
              % Baseline, focal length, and pixel size
              b = app.baseline * 1000;    % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = app.width / 2;  
              cyLeft = app.height/ 2;  
              cxRight = app.width / 2;  
              cyRight = app.height / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (-1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = app.camZ - Z;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITableCalcPyCon.Data = T;
              scatter(app.UIAxes_presX, app.sampledServeCounter,(X - app.x_pres),5, "green");
              hold(app.UIAxes_presX, "on");
              scatter(app.UIAxes_presY, app.sampledServeCounter,(Y - app.y_pres),5, "green");
              hold(app.UIAxes_presY, "on");
              scatter(app.UIAxes_presZ, app.sampledServeCounter,(Z1 - app.z_pres),5, "green");
              hold(app.UIAxesZError1, "on");

              app.x_dat = [app.x_dat X];
              app.y_dat = [app.y_dat Y];
              app.z_dat = [app.z_dat Z1];

              %if (app.courtCov == 1)
              %   app.isDetectedFlag = 1;
              %   if (app.isDetectedPrev == 0)
              %       disp("Ball found at " + app.x_pres + " " + app.y_pres + " " + app.z_pres)
              %   end
              %   app.covX = app.x_pres;
              %   app.covY = app.y_pres;
              %   app.covZ = app.z_pres;
              %end
      
          else
             app.x_dat = [app.x_dat 12453216];
             app.y_dat = [app.y_dat 12453216];
             app.z_dat = [app.z_dat 12453216];
             %if (app.courtCov == 1)
             %   app.isDetectedFlag = 0;
             %   if (app.isDetectedPrev == 1)
             %       disp("Ball lost, last seen at " + app.covX + " " + app.covY + " " + app.covZ)
             %   end
             %end
          end
       end


        %algorithm_lite implements the grayscale-sobel-circles algorithm in
        %Python. Algorithm code provided by Toby.
        function algorithm_py_sobel(app)
           mdatL = pyrunfile("ball_detect_sobel.py --image blender_L_im_.jpg", "center_rad");
           mdatR = pyrunfile("ball_detect_sobel.py --image blender_R_im_.jpg", "center_rad");

           %Conversion from py arrays to Matlab arrays
           mdatL = cellfun(@double, cell(mdatL));
           mdatR = cellfun(@double, cell(mdatR));
           % 7. Extract Centroid
           if mdatL  == [-100, -100, -100]
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPySobel.Data = T;
               disp("hello")
               mdatL = zeros(0,0,0);
               
           elseif ~isempty(mdatL)
               xLeft = mdatL(1);
               yLeft = mdatL(2);
               centers_L = [mdatL(1), mdatL(2)];
               T = table(mdatL(1), mdatL(2));
               app.UITableLeftPySobel.Data = T;
           else
               disp(mdatL(1))
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPySobel.Data = T;
           end

           if mdatR == [-100, -100, -100]
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableLeftPySobel.Data = T;
               mdatR = zeros(0,0,0);

           elseif ~isempty(mdatR)
               xRight = mdatR(1);
               yRight = mdatR(2);
               centers_R = [mdatR(1), mdatR(2)];
               T = table(mdatR(1), mdatR(2));
               app.UITableRightPySobel.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightPySobel.Data = T;
           end
       
           %% 8. Display Results
           if (app.showImg == 0)
               imagesc(app.UIAxesLeftPySobel, app.imageLeft, [0, 255]); 
               colormap(app.UIAxesLeftPySobel, 'gray');
               hold(app.UIAxesLeftPySobel, 'on');
               if ~isempty(mdatL)
                   viscircles(app.UIAxesLeftPySobel, centers_L, mdatL(3), 'EdgeColor', 'r');
                   plot(app.UIAxesLeftPySobel, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesLeftPySobel, 'off');
               
               imagesc(app.UIAxesRightPySobel, app.imageRight, [0, 255]); 
               colormap(app.UIAxesRightPySobel, 'gray');
               hold(app.UIAxesRightPySobel, 'on');
               if ~isempty(mdatR)
                   viscircles(app.UIAxesRightPySobel, centers_R, mdatR(3), 'EdgeColor', 'r');
                   plot(app.UIAxesRightPySobel, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesRightPySobel, 'off');

           elseif (app.courtCov == 1)
               if ~isempty(mdatL) &&  ~isempty(mdatR)
                  plot(app.UIAxesLeftPySobel, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
                  plot(app.UIAxesRightPySobel, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
           end


          % 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if (~isnan(xLeft) && ~isnan(xRight) )
              % Baseline, focal length, and pixel size
              b = app.baseline * 1000;    % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = app.width / 2;  
              cyLeft = app.height / 2;  
              cxRight = app.width / 2;  
              cyRight = app.height / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (-1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = app.camZ - Z;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITablePySobel.Data = T;
              scatter(app.UIAxes_presX, app.sampledServeCounter,(X - app.x_pres),5, "blue");
              hold(app.UIAxes_presX, "on");
              scatter(app.UIAxes_presY, app.sampledServeCounter,(Y - app.y_pres),5, "blue");
              hold(app.UIAxes_presY, "on");
              scatter(app.UIAxes_presZ, app.sampledServeCounter,(Z1 - app.z_pres),5, "blue");
              hold(app.UIAxes_presZ, "on");
          else
              disp('Ball not detected in both images.');
              T = table();
              app.UITablePySobel.Data = T;
          end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initializes parameters to app design values
            app.fileType = app.DataTypeDropDown.Value;
            app.width = app.CameraRespxSpinner.Value;
            app.serve_num = app.DataFileSpinner.Value;
            app.camX = app.camXSpinner.Value;
            app.camY = app.camYSpinner.Value;
            app.camZ = app.camZSpinner.Value;
            app.baseline = app.CameraBaseline.Value;
            app.showImg = app.HideImagesButton.Value;
            app.isDetectedFlag = 0;
            app.rangeOne = app.mRangeButton.Value;
            app.repeatFlag = 0;


        end

        % Value changed function: CameraRespxSpinner
        function CameraRespxSpinnerValueChanged(app, event)
           app.width = app.CameraRespxSpinner.Value;
        end

        % Value changed function: DataFileSpinner
        function DataFileSpinnerValueChanged(app, event)
            app.serve_num = app.DataFileSpinner.Value;
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            app.fileType = app.DataTypeDropDown.Value;
            
        end

        % Button pushed function: RunSelectionButton
        function RunSelectionButtonPushed(app, event)
            %Sets Lamp to indicate status of simulation
            app.CompleteLamp.Color = [1.00,0.00,0.00];
            serve_run(app);
            app.CompleteLamp.Color = [0.00,1.00,0.00];
        end

        % Button pushed function: RunAllSelectedButton
        function RunAllSelectedButtonPushed(app, event)
            %Sets Lamp to indicate status of simulation
            app.CompleteLamp.Color = [1.00,0.00,0.00];
            serve_bluk(app);
            app.CompleteLamp.Color = [0.00,1.00,0.00];
        end

        % Value changed function: CameraBaseline
        function CameraBaselineValueChanged(app, event)
            app.baseline = app.CameraBaseline.Value;
            
        end

        % Value changed function: camXSpinner
        function camXSpinnerValueChanged(app, event)
            app.camX = app.camXSpinner.Value;
            
        end

        % Value changed function: camYSpinner
        function camYSpinnerValueChanged(app, event)
            app.camY = app.camYSpinner.Value;
            
        end

        % Value changed function: camZSpinner
        function camZSpinnerValueChanged(app, event)
            app.camZ = app.camZSpinner.Value;
            
        end

        % Value changed function: HideImagesButton
        function HideImagesButtonValueChanged(app, event)
            app.showImg = app.HideImagesButton.Value;
            
        end

        % Button pushed function: RunCoverageTest
        function RunCoverageTestButtonPushed(app, event)
            app.CompleteLamp.Color = [1.00,0.00,0.00];
            court_coverage_test(app);
            app.CompleteLamp.Color = [0.00,1.00,0.00];
        end

        % Value changed function: mRangeButton
        function mRangeButtonValueChanged(app, event)
            app.rangeOne = app.mRangeButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesRightLite = uiaxes(app.UIFigure);
            title(app.UIAxesRightLite, 'Right Matlab Sobel')
            xlabel(app.UIAxesRightLite, 'X')
            ylabel(app.UIAxesRightLite, 'Y')
            zlabel(app.UIAxesRightLite, 'Z')
            app.UIAxesLeftPySobel = uiaxes(app.UIFigure);
            title(app.UIAxesLeftPySobel, 'Left Python Sobel')
            xlabel(app.UIAxesLeftPySobel, 'X')
            ylabel(app.UIAxesLeftPySobel, 'Y')
            zlabel(app.UIAxesLeftPySobel, 'Z')
            app.UIAxesRightPySobel = uiaxes(app.UIFigure);
            title(app.UIAxesRightPySobel, 'Right Python Sobel')
            xlabel(app.UIAxesRightPySobel, 'X')
            ylabel(app.UIAxesRightPySobel, 'Y')
            zlabel(app.UIAxesRightPySobel, 'Z')
            app.UIAxesLeftOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesLeftOriginal, 'Left  Blender Image')
            xlabel(app.UIAxesLeftOriginal, 'X')
            ylabel(app.UIAxesLeftOriginal, 'Y')
            zlabel(app.UIAxesLeftOriginal, 'Z')
            app.UIAxesRightOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesRightOriginal, 'Right Blender Image')
            xlabel(app.UIAxesRightOriginal, 'X')
            ylabel(app.UIAxesRightOriginal, 'Y')
            zlabel(app.UIAxesRightOriginal, 'Z')
            app.UIAxesLeftPyContour = uiaxes(app.UIFigure);
            title(app.UIAxesLeftPyContour, 'Left Python Contour')
            xlabel(app.UIAxesLeftPyContour, 'X')
            ylabel(app.UIAxesLeftPyContour, 'Y')
            zlabel(app.UIAxesLeftPyContour, 'Z')
            app.UIAxesRightPyContour = uiaxes(app.UIFigure);
            title(app.UIAxesRightPyContour, 'Right Python Contour')
            xlabel(app.UIAxesRightPyContour, 'X')
            ylabel(app.UIAxesRightPyContour, 'Y')
            zlabel(app.UIAxesRightPyContour, 'Z')
            app.UIAxesXError1 = uiaxes(app.UIFigure);
            title(app.UIAxesXError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesXError1, 'Image')
            ylabel(app.UIAxesXError1, 'Error (m)')
            zlabel(app.UIAxesXError1, 'Z')
            app.UIAxesXError4 = uiaxes(app.UIFigure);
            title(app.UIAxesXError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesXError4, 'Image')
            ylabel(app.UIAxesXError4, 'Error (m)')
            zlabel(app.UIAxesXError4, 'Z')
            app.UIAxesXError5 = uiaxes(app.UIFigure);
            title(app.UIAxesXError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesXError5, 'Image')
            ylabel(app.UIAxesXError5, 'Error (m)')
            zlabel(app.UIAxesXError5, 'Z')
            app.UIAxesXError2 = uiaxes(app.UIFigure);
            title(app.UIAxesXError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesXError2, 'Image')
            ylabel(app.UIAxesXError2, 'Error (m)')
            zlabel(app.UIAxesXError2, 'Z')
            app.UIAxesXError3 = uiaxes(app.UIFigure);
            title(app.UIAxesXError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesXError3, 'Image')
            ylabel(app.UIAxesXError3, 'Error (m)')
            zlabel(app.UIAxesXError3, 'Z')
            app.UIAxesLeftLite = uiaxes(app.UIFigure);
            title(app.UIAxesLeftLite, 'Left Matlab Sobel')
            xlabel(app.UIAxesLeftLite, 'X')
            ylabel(app.UIAxesLeftLite, 'Y')
            zlabel(app.UIAxesLeftLite, 'Z')
            app.UIAxesYError1 = uiaxes(app.UIFigure);
            title(app.UIAxesYError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesYError1, 'Image')
            ylabel(app.UIAxesYError1, 'Error (m)')
            zlabel(app.UIAxesYError1, 'Z')
            app.UIAxesYError4 = uiaxes(app.UIFigure);
            title(app.UIAxesYError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesYError4, 'Image')
            ylabel(app.UIAxesYError4, 'Error (m)')
            zlabel(app.UIAxesYError4, 'Z')
            app.UIAxesYError5 = uiaxes(app.UIFigure);
            title(app.UIAxesYError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesYError5, 'Image')
            ylabel(app.UIAxesYError5, 'Error (m)')
            zlabel(app.UIAxesYError5, 'Z')
            app.UIAxesYError2 = uiaxes(app.UIFigure);
            title(app.UIAxesYError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesYError2, 'Image')
            ylabel(app.UIAxesYError2, 'Error (m)')
            zlabel(app.UIAxesYError2, 'Z')
            app.UIAxesYError3 = uiaxes(app.UIFigure);
            title(app.UIAxesYError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesYError3, 'Image')
            ylabel(app.UIAxesYError3, 'Error (m)')
            zlabel(app.UIAxesYError3, 'Z')
            app.UIAxesZError1 = uiaxes(app.UIFigure);
            title(app.UIAxesZError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesZError1, 'Image')
            ylabel(app.UIAxesZError1, 'Error (m)')
            zlabel(app.UIAxesZError1, 'Z')
            app.UIAxesZError4 = uiaxes(app.UIFigure);
            title(app.UIAxesZError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesZError4, 'Image')
            ylabel(app.UIAxesZError4, 'Error (m)')
            zlabel(app.UIAxesZError4, 'Z')
            app.UIAxesZError5 = uiaxes(app.UIFigure);
            title(app.UIAxesZError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesZError5, 'Image')
            ylabel(app.UIAxesZError5, 'Error (m)')
            zlabel(app.UIAxesZError5, 'Z')
            app.UIAxesZError2 = uiaxes(app.UIFigure);
            title(app.UIAxesZError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesZError2, 'Image')
            ylabel(app.UIAxesZError2, 'Error (m)')
            zlabel(app.UIAxesZError2, 'Z')
            app.UIAxesZError3 = uiaxes(app.UIFigure);
            title(app.UIAxesZError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesZError3, 'Image')
            ylabel(app.UIAxesZError3, 'Error (m)')
            zlabel(app.UIAxesZError3, 'Z')
            app.UITableLeftPySobel = uitable(app.UIFigure);
            app.UITableLeftPySobel.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftPySobel.RowName = {};
            app.UITableRightPySobel = uitable(app.UIFigure);
            app.UITableRightPySobel.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightPySobel.RowName = {};
            app.UITablePySobel = uitable(app.UIFigure);
            app.UITablePySobel.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITablePySobel.RowName = {};
            app.UITableLeftLite = uitable(app.UIFigure);
            app.UITableLeftLite.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftLite.RowName = {};
            app.UITableRightLite = uitable(app.UIFigure);
            app.UITableRightLite.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightLite.RowName = {};
            app.UITableClacLite = uitable(app.UIFigure);
            app.UITableClacLite.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacLite.RowName = {};
            app.UITableExpected = uitable(app.UIFigure);
            app.UITableExpected.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableExpected.RowName = {};
            app.CameraRespxSpinnerLabel = uilabel(app.UIFigure);
            app.CameraRespxSpinnerLabel.Text = 'Camera Res (px)';
            app.CameraRespxSpinner = uispinner(app.UIFigure);
            app.CameraRespxSpinner.Step = 1168;
            app.CameraRespxSpinner.Limits = [752 1920];
            app.CameraRespxSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraRespxSpinnerValueChanged, true);
            app.CameraRespxSpinner.Value = 1920;
            app.DataFileSpinnerLabel = uilabel(app.UIFigure);
            app.DataFileSpinnerLabel.Text = 'Data File';
            app.DataFileSpinner = uispinner(app.UIFigure);
            app.DataFileSpinner.Limits = [1 5];
            app.DataFileSpinner.ValueChangedFcn = createCallbackFcn(app, @DataFileSpinnerValueChanged, true);
            app.DataFileSpinner.Value = 1;
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'serve', 'volley'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'serve';
            app.UITableLeftPyCon = uitable(app.UIFigure);
            app.UITableLeftPyCon.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftPyCon.RowName = {};
            app.UITableRightPyCon = uitable(app.UIFigure);
            app.UITableRightPyCon.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightPyCon.RowName = {};
            app.UITableCalcPyCon = uitable(app.UIFigure);
            app.UITableCalcPyCon.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableCalcPyCon.RowName = {};
            app.ActualPositionfromBlenderLabel = uilabel(app.UIFigure);
            app.ActualPositionfromBlenderLabel.Text = 'Actual Position from Blender';
            app.MatlabSobelCircleFilterResultsLabel = uilabel(app.UIFigure);
            app.MatlabSobelCircleFilterResultsLabel.Text = 'Matlab Sobel Circle Filter Results';
            app.PythonSobelCircleFilterResultsLabel = uilabel(app.UIFigure);
            app.PythonSobelCircleFilterResultsLabel.Text = 'Python Sobel Circle Filter Results';
            app.PythonGreenContourResultsLabel = uilabel(app.UIFigure);
            app.PythonGreenContourResultsLabel.Text = 'Python Green Contour Results';
            app.AquaTrackAlgorithmBulkTestCaseAppLabel = uilabel(app.UIFigure);
            app.AquaTrackAlgorithmBulkTestCaseAppLabel.Text = 'AquaTrack Algorithm Bulk Test Case App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Sprite-0001.png');
            app.RunSelectionButton = uibutton(app.UIFigure, 'push');
            app.RunSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunSelectionButtonPushed, true);
            app.RunSelectionButton.Text = 'Run Selection';
            app.RunAllSelectedButton = uibutton(app.UIFigure, 'push');
            app.RunAllSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllSelectedButtonPushed, true);
            app.RunAllSelectedButton.Text = 'Run All Selected';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.CompleteLampLabel = uilabel(app.UIFigure);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.UIFigure);
            app.XmSpinnerLabel = uilabel(app.UIFigure);
            app.XmSpinnerLabel.Text = 'X (m)';
            app.camXSpinner = uispinner(app.UIFigure);
            app.camXSpinner.Step = 0.01;
            app.camXSpinner.Limits = [0 100];
            app.camXSpinner.ValueChangedFcn = createCallbackFcn(app, @camXSpinnerValueChanged, true);
            app.YmSpinnerLabel = uilabel(app.UIFigure);
            app.YmSpinnerLabel.Text = 'Y (m)';
            app.camYSpinner = uispinner(app.UIFigure);
            app.camYSpinner.Step = 0.01;
            app.camYSpinner.Limits = [0 100];
            app.camYSpinner.ValueChangedFcn = createCallbackFcn(app, @camYSpinnerValueChanged, true);
            app.ZmSpinnerLabel = uilabel(app.UIFigure);
            app.ZmSpinnerLabel.Text = 'Z (m)';
            app.camZSpinner = uispinner(app.UIFigure);
            app.camZSpinner.Step = 0.01;
            app.camZSpinner.Limits = [0 100];
            app.camZSpinner.ValueChangedFcn = createCallbackFcn(app, @camZSpinnerValueChanged, true);
            app.camZSpinner.Value = 11;
            app.CameraLocationLabel = uilabel(app.UIFigure);
            app.CameraLocationLabel.Text = {'Camera'; 'Location'};
            app.CameraRespxSpinnerLabel_2 = uilabel(app.UIFigure);
            app.CameraRespxSpinnerLabel_2.Text = 'Camera Baseline (m)';
            app.CameraBaseline = uispinner(app.UIFigure);
            app.CameraBaseline.Step = 0.001;
            app.CameraBaseline.Limits = [0 100];
            app.CameraBaseline.ValueChangedFcn = createCallbackFcn(app, @CameraBaselineValueChanged, true);
            app.CameraBaseline.Value = 0.06;
            app.HideImagesButton = uibutton(app.UIFigure, 'state');
            app.HideImagesButton.ValueChangedFcn = createCallbackFcn(app, @HideImagesButtonValueChanged, true);
            app.HideImagesButton.Text = 'Hide Images';
            app.RunCoverageTest = uibutton(app.UIFigure, 'push');
            app.RunCoverageTest.ButtonPushedFcn = createCallbackFcn(app, @RunCoverageTestButtonPushed, true);
            app.RunCoverageTest.Text = 'Run Coverage Test';
            app.StepIncrementButtonGroup = uibuttongroup(app.UIFigure);
            app.StepIncrementButtonGroup.Title = 'Step Increment';
            app.Button = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button.Text = '0.01';
            app.Button_2 = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button_2.Text = '0.1';
            app.Button_3 = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button_3.Text = '1';
            app.Button_3.Value = true;
            app.mRangeButton = uibutton(app.UIFigure, 'state');
            app.mRangeButton.ValueChangedFcn = createCallbackFcn(app, @mRangeButtonValueChanged, true);
            app.mRangeButton.Text = '5m Range';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Algorithm_Bulk_Tester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end