classdef Implementation_Verifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mRangeButton                    matlab.ui.control.StateButton
        StepIncrementButtonGroup        matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.ToggleButton
        Button_2                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        RunCoverageTest                 matlab.ui.control.Button
        HideImagesButton                matlab.ui.control.StateButton
        CameraBaseline                  matlab.ui.control.Spinner
        CameraRespxSpinnerLabel_2       matlab.ui.control.Label
        CameraLocationLabel             matlab.ui.control.Label
        camZSpinner                     matlab.ui.control.Spinner
        ZmSpinnerLabel                  matlab.ui.control.Label
        camYSpinner                     matlab.ui.control.Spinner
        YmSpinnerLabel                  matlab.ui.control.Label
        camXSpinner                     matlab.ui.control.Spinner
        XmSpinnerLabel                  matlab.ui.control.Label
        CompleteLamp                    matlab.ui.control.Lamp
        CompleteLampLabel               matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        RunSelectionButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        AquaTrackAlgorithmBulkTestCaseAppLabel  matlab.ui.control.Label
        PythonGreenContourResultsLabel  matlab.ui.control.Label
        PythonSobelCircleFilterResultsLabel  matlab.ui.control.Label
        MatlabSobelCircleFilterResultsLabel  matlab.ui.control.Label
        ActualPositionfromBlenderLabel  matlab.ui.control.Label
        UITableCalcPyCon                matlab.ui.control.Table
        UITableRightPyCon               matlab.ui.control.Table
        UITableLeftPyCon                matlab.ui.control.Table
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        DataFileSpinner                 matlab.ui.control.Spinner
        DataFileSpinnerLabel            matlab.ui.control.Label
        CameraRespxSpinner              matlab.ui.control.Spinner
        CameraRespxSpinnerLabel         matlab.ui.control.Label
        UITableExpected                 matlab.ui.control.Table
        UITableClacLite                 matlab.ui.control.Table
        UITableRightLite                matlab.ui.control.Table
        UITableLeftLite                 matlab.ui.control.Table
        UITablePySobel                  matlab.ui.control.Table
        UITableRightPySobel             matlab.ui.control.Table
        UITableLeftPySobel              matlab.ui.control.Table
        UIAxesZError3                   matlab.ui.control.UIAxes
        UIAxesZError2                   matlab.ui.control.UIAxes
        UIAxesZError5                   matlab.ui.control.UIAxes
        UIAxesZError4                   matlab.ui.control.UIAxes
        UIAxesZError1                   matlab.ui.control.UIAxes
        UIAxesYError3                   matlab.ui.control.UIAxes
        UIAxesYError2                   matlab.ui.control.UIAxes
        UIAxesYError5                   matlab.ui.control.UIAxes
        UIAxesYError4                   matlab.ui.control.UIAxes
        UIAxesYError1                   matlab.ui.control.UIAxes
        UIAxesLeftLite                  matlab.ui.control.UIAxes
        UIAxesXError3                   matlab.ui.control.UIAxes
        UIAxesXError2                   matlab.ui.control.UIAxes
        UIAxesXError5                   matlab.ui.control.UIAxes
        UIAxesXError4                   matlab.ui.control.UIAxes
        UIAxesXError1                   matlab.ui.control.UIAxes
        UIAxesRightPyContour            matlab.ui.control.UIAxes
        UIAxesLeftPyContour             matlab.ui.control.UIAxes
        UIAxesRightOriginal             matlab.ui.control.UIAxes
        UIAxesLeftOriginal              matlab.ui.control.UIAxes
        UIAxesRightPySobel              matlab.ui.control.UIAxes
        UIAxesLeftPySobel               matlab.ui.control.UIAxes
        UIAxesRightLite                 matlab.ui.control.UIAxes
    end

 
%Algorith Bulk Tester TODO
%
% Add more parameterization 
%    (All camera components, image plots on or off, sample rate, etc)
%
% Ask teammate to double check calculatios and formulas
%
% Make how Python receives images cleaner
%
% Adjust algorithms if needed based on current and after checked
% performance


    properties (Access = private)
        %Camera Parameters
        width
        height
        camX
        camY
        camZ
        baseline

        %Toogle for whether to show images
        showImg

        %Image Organization
        all_img_red
        all_img_grn
        all_img_blu

        %Active Images
        imageLeft  
        imageRight 

        %Coverage Test Parameters
        courtCov
        isDetectedFlag
        isDetectedPrev
        covX
        covY
        covZ
        rangeOne

        %Blender Server Client, needed for seperation of bulk and non-bulk
        %functions
        blend_client

        numFrames
        framesTimeMs

        %Which file type and which number is selected
        serve_num
        fileType

        %Active X, Y, and Z values for Error scatter plots
        x_pres
        y_pres
        z_pres

        %Plot variables that let the case statement only exist once
        UIAxes_presX
        UIAxes_presY
        UIAxes_presZ

        %Frame loop value
        sampledServeCounter
        repeatFlag
    end
    
    methods (Access = public)

        % image_gen generates all of the images and calls the algorithms.
        % It MUST be called withing serve_run or serve_bulk, as it only
        % makes the requests to Blender, not the initial connection. This
        % was done so the bulk function would not pause each time a file is
        % complete

        function court_coverage_test(app)
           %app.showImg = 1;
           app.courtCov = 1;

           app.isDetectedFlag = 0;
           app.isDetectedPrev = 0;

           %Clearing Images
           app.all_img_red = [];
           app.all_img_grn = [];
           app.all_img_blu = [];

           %Clearing Plots
           cla(app.UIAxesXError1);
           cla(app.UIAxesYError1);
           cla(app.UIAxesZError1);
           cla(app.UIAxesXError2);
           cla(app.UIAxesYError2);
           cla(app.UIAxesZError2);
           cla(app.UIAxesXError3);
           cla(app.UIAxesYError3);
           cla(app.UIAxesZError3);
           cla(app.UIAxesXError4);
           cla(app.UIAxesYError4);
           cla(app.UIAxesZError4);
           cla(app.UIAxesXError5);
           cla(app.UIAxesYError5);
           cla(app.UIAxesZError5);

           cla(app.UIAxesLeftPyContour);
           cla(app.UIAxesRightPyContour);
           cla(app.UIAxesLeftLite);
           cla(app.UIAxesRightPySobel);
           cla(app.UIAxesRightLite);
           cla(app.UIAxesLeftPySobel);

           %Initializing Camera Parameters 
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end

           hold(app.UIAxesLeftPyContour, 'on');
           hold(app.UIAxesRightPyContour, 'on')
           hold(app.UIAxesLeftLite, 'on');
           hold(app.UIAxesRightLite, 'on');
           hold(app.UIAxesLeftPySobel, 'on');
           hold(app.UIAxesRightPySobel, 'on');
           app.UIAxes_presX = app.UIAxesXError1;
           app.UIAxes_presY = app.UIAxesYError1;
           app.UIAxes_presZ = app.UIAxesZError1;
           hold(app.UIAxes_presX, "on");
           hold(app.UIAxes_presY, "on");
           hold(app.UIAxes_presZ, "on");

           if app.rangeOne == 1
              ylim(app.UIAxes_presX, [-5 5]);
              ylim(app.UIAxes_presY, [-5 5]);
              ylim(app.UIAxes_presZ, [-5 5]);
           end

           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever

           app.blend_client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");

           app.sampledServeCounter = 0;
           for x_cc = 0:1:21
                for y_cc = 0:1:11

                   app.x_pres = (x_cc - 10.5);
                   app.y_pres = (y_cc - 5.5);
                   app.z_pres = 0.5;
                   T = table(app.x_pres, app.y_pres, 0.5);
                   app.UITableExpected.Data = T;
                   app.sampledServeCounter = app.sampledServeCounter + 1;

                   blenderLink(app.blend_client, app.width,app.height,app.x_pres, app.y_pres, app.z_pres,0,0,0,"tennisBall");
                   
                   %Go from real to pixel, where real is the edge of mono frame
                   app.imageLeft = blenderLink(app.blend_client, app.width, app.height, (app.camX - (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");

                   %Generate the Right Image
                   app.imageRight = blenderLink(app.blend_client, app.width, app.height, (app.camX + (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera")
                   if(x_cc == 0) && (y_cc == 0)
                       imagesc(app.UIAxesLeftPyContour, app.imageLeft, [0, 255]) 
                       imagesc(app.UIAxesLeftPySobel, app.imageLeft, [0, 255])
                   
                   
                       imagesc(app.UIAxesRightPyContour, app.imageRight, [0, 255]) 
                       imagesc(app.UIAxesRightPySobel, app.imageRight, [0, 255])
                   end

                   imwrite(app.imageLeft, "blender_L_im_.jpg");
                   imwrite(app.imageRight, "blender_R_im_.jpg");

                   app.all_img_red = [app.all_img_red app.imageRight(:,:,1) app.imageLeft(:,:,1)];
                   app.all_img_grn = [app.all_img_grn app.imageRight(:,:,2) app.imageLeft(:,:,2)];
                   app.all_img_blu = [app.all_img_blu app.imageRight(:,:,3) app.imageLeft(:,:,3)];
       
                   algorithm_py_contour(app);

                   app.isDetectedPrev = app.isDetectedFlag;
   
                end
           end

           clear app.blend_client

           algorithm_implementation_cover(app);


            hold(app.UIAxesLeftPyContour, 'off');
            hold(app.UIAxesRightPyContour, 'off');
            hold(app.UIAxesLeftLite, 'off');
            hold(app.UIAxesRightLite, 'off');
            hold(app.UIAxesLeftPySobel, 'off');
            hold(app.UIAxesRightPySobel, 'off');
            app.courtCov = 0;
            app.showImg = 0;
        end


        function image_gen(app)
           %Reads selected/active .dat file
           serve = readmatrix("/datFiles/"+ app.fileType + num2str(app.serve_num) + ".dat");
           dataSize = size(serve);
           app.numFrames = dataSize(1);
           app.sampledServeCounter = 1;

           app.repeatFlag = 0;
           
           %TODO: Make framesTimeMs a toggle-able setting, may be useful for in
           %depth, but slow testing
           app.framesTimeMs = 50;
           
           %Importing serve data. This is front loaded to save memory
           %allocation time, as well as the check to break early once the
           %simulation has ended
           x = serve(:,1);
           y = serve(:,3);
           z = serve(:,2);
           %For the number of frames the device will see, generate images


           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever

           app.blend_client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");

           %Image Management code by August
           for counter = 1:app.framesTimeMs:app.numFrames 
               %Move the Tennis Ball. Due to how the files are formated, x
               %and y are swapped. See the professor's original code on myCourses for
               %clear example: serveBall.m
               blenderLink(app.blend_client,app.width,app.height,y(counter),x(counter),z(counter),0,0,0,"tennisBall");
               %Generate the Left Image
               app.imageLeft = blenderLink(app.blend_client, app.width, app.height, (app.camX - (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
               if (app.showImg == 0)
                  imagesc(app.UIAxesLeftOriginal, app.imageLeft, [0, 255]) % Apply fixed intensity range
               end
               %Generate the Right Image
               app.imageRight = blenderLink(app.blend_client, app.width, app.height, (app.camX + (app.baseline/2)), app.camY, app.camZ, 0, 0, 0, "Camera");
               if (app.showImg == 0)
                  imagesc(app.UIAxesRightOriginal, app.imageRight, [0, 255]) % Apply fixed intensity range
               end

               app.all_img_red = [app.all_img_red app.imageRight(:,:,1) app.imageLeft(:,:,1)];
               app.all_img_grn = [app.all_img_grn app.imageRight(:,:,2) app.imageLeft(:,:,2)];
               app.all_img_blu = [app.all_img_blu app.imageRight(:,:,3) app.imageLeft(:,:,3)];

               if app.sampledServeCounter == 1
                  rec_img = cat(3, app.all_img_red(:,1:752), app.all_img_grn(:,1:752), app.all_img_blu(:,1:752));
                  imagesc(app.UIAxesRightLite, rec_img);

                  rec_img = cat(3, app.all_img_red(:,753:1504), app.all_img_grn(:,753:1504), app.all_img_blu(:,753:1504));
                  imagesc(app.UIAxesLeftLite, rec_img);
               else
                  pres_start = ((((2*app.sampledServeCounter)-2)*app.width)+1);
                  pres_end = (((2*app.sampledServeCounter)-1) * app.width);
                  rec_img = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
                  imagesc(app.UIAxesRightLite, rec_img);
   
                  pres_start = ((((2*app.sampledServeCounter)-1)*app.width)+1);
                  pres_end = ((2*app.sampledServeCounter) * app.width);
                  rec_img = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
                  imagesc(app.UIAxesLeftLite, rec_img);
               end


               %Display expected values from Blender
               T = table(y(counter), x(counter), z(counter));
               app.UITableExpected.Data = T;
           
               %TODO: IMPROVE HOW PYTHON RECIEVES IMAGES
               %Easiest solution was to save only the current file to pass
               %to python. This is of course not ideal. Get Matlab to
               %Python Array transfers working
               imwrite(app.imageLeft, "blender_L_im_.jpg");
               imwrite(app.imageRight, "blender_R_im_.jpg");
           
               %Updates current X,Y,Z values so functions can see them
               app.x_pres = y(counter);
               app.y_pres = x(counter);
               app.z_pres = z(counter);


               % Case statement decides which graph to plot on   
               switch app.serve_num
                  case 1
                     app.UIAxes_presX = app.UIAxesXError1;
                     app.UIAxes_presY = app.UIAxesYError1;
                     app.UIAxes_presZ = app.UIAxesZError1;
                  case 2
                     app.UIAxes_presX = app.UIAxesXError2;
                     app.UIAxes_presY = app.UIAxesYError2;
                     app.UIAxes_presZ = app.UIAxesZError2;
                  case 3
                     app.UIAxes_presX = app.UIAxesXError3;
                     app.UIAxes_presY = app.UIAxesYError3;
                     app.UIAxes_presZ = app.UIAxesZError3;
                  case 4
                     app.UIAxes_presX = app.UIAxesXError4;
                     app.UIAxes_presY = app.UIAxesYError4;
                     app.UIAxes_presZ = app.UIAxesZError4;
                  case 5
                     app.UIAxes_presX = app.UIAxesXError5;
                     app.UIAxes_presY = app.UIAxesYError5;
                     app.UIAxes_presZ = app.UIAxesZError5;
                  case others
                     app.UIAxes_presX = app.UIAxesXError1;
                     app.UIAxes_presY = app.UIAxesYError1;
                     app.UIAxes_presZ = app.UIAxesZError1;
              end


              if ((app.x_pres > 10) | (-10 > app.x_pres)) | ((app.y_pres > 6) |  (app.y_pres < -6))
                  pgon = polyshape([(-0.5 + app.sampledServeCounter)  (-0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter)  (0.5 + app.sampledServeCounter) ],[-10 10 10 -10]);
                  pg = plot(app.UIAxes_presX, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
                  pg = plot(app.UIAxes_presY, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
                  pg = plot(app.UIAxes_presZ, pgon);
                  pg.FaceColor = "red";
                  pg.EdgeColor = "red";
              end

              if app.rangeOne == 1
                 ylim(app.UIAxes_presX, [-5 5]);
                 ylim(app.UIAxes_presY, [-5 5]);
                 ylim(app.UIAxes_presZ, [-5 5]);
              end
              %Algorithms are called here
              algorithm_py_contour(app);

              app.sampledServeCounter = app.sampledServeCounter + 1;

              %Break condition. This is used as the data files will
              %sometimes have excess post-movement data that is not needed
              %for simulation
              if ((x(counter) == x(app.numFrames)) && (y(counter) == y(app.numFrames)))
                  break
              end
           end

           clear app.blend_client

           algorithm_implementation_serve(app);


        end

        %Top Level Function for calling a single file test. This function
        %establishes the Blender-Matlab link, clears plots if used
        %previously, and sets any changed parameters
        function serve_run(app)

           %Clearing Images
           app.all_img_red = [];
           app.all_img_grn = [];
           app.all_img_blu = [];

           %Clearing Plots

           cla(app.UIAxesLeftPyContour);
           cla(app.UIAxesRightPyContour);
           cla(app.UIAxesLeftLite);
           cla(app.UIAxesRightPySobel);
           cla(app.UIAxesRightLite);
           cla(app.UIAxesLeftPySobel);


           %Initializing Camera Parameters 
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end

           switch app.serve_num
               case 1
                  cla(app.UIAxesXError1);
                  cla(app.UIAxesYError1);
                  cla(app.UIAxesZError1);
               case 2
                  cla(app.UIAxesXError2);
                  cla(app.UIAxesYError2);
                  cla(app.UIAxesZError2);
               case 3
                  cla(app.UIAxesXError3);
                  cla(app.UIAxesYError3);
                  cla(app.UIAxesZError3);
               case 4
                  cla(app.UIAxesXError4);
                  cla(app.UIAxesYError4);
                  cla(app.UIAxesZError4);
               case 5
                  cla(app.UIAxesXError5);
                  cla(app.UIAxesYError5);
                  cla(app.UIAxesZError5);
               case others
                  cla(app.UIAxesXError1);
                  cla(app.UIAxesYError1);
                  cla(app.UIAxesZError1);
                  cla(app.UIAxesXError2);
                  cla(app.UIAxesYError2);
                  cla(app.UIAxesZError2);
                  cla(app.UIAxesXError3);
                  cla(app.UIAxesYError3);
                  cla(app.UIAxesZError3);
                  cla(app.UIAxesXError4);
                  cla(app.UIAxesYError4);
                  cla(app.UIAxesZError4);
                  cla(app.UIAxesXError5);
                  cla(app.UIAxesYError5);
                  cla(app.UIAxesZError5);
           end

           %Image generation call
           image_gen(app)

        end 
       

        %algorithm_lite implements the green channel contour algorithm in
        %Python. Algorithm code by Ethan.
        function algorithm_py_contour(app)
           mdatL = pyrunfile("ball_tracking_img.py --image blender_L_im_.jpg", "center_rad");
           mdatR = pyrunfile("ball_tracking_img.py --image blender_R_im_.jpg", "center_rad");
            
          %Conversion from py arrays to Matlab arrays
           mdatL = cellfun(@double, cell(mdatL));
           mdatR = cellfun(@double, cell(mdatR));
           % 7. Extract Centroid
           if mdatL  == [-100, -100, -100]
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
               mdatL = zeros(0,0,0);
               
           elseif ~isempty(mdatL)
               xLeft = mdatL(1);
               yLeft = mdatL(2);
               centers_L = [mdatL(1), mdatL(2)];
               T = table(mdatL(1), mdatL(2));
               app.UITableLeftPyCon.Data = T;
           else
               disp(mdatL(1))
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
           end

           if mdatR == [-100, -100, -100]
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableLeftPyCon.Data = T;
               mdatR = zeros(0,0,0);

           elseif ~isempty(mdatR)
               xRight = mdatR(1);
               yRight = mdatR(2);
               centers_R = [mdatR(1), mdatR(2)];
               T = table(mdatR(1), mdatR(2));
               app.UITableRightPyCon.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightPyCon.Data = T;
           end
       
           %% 8. Display Results
           if (app.showImg == 0)
               imagesc(app.UIAxesLeftPyContour, app.imageLeft, [0, 255]); 
               colormap(app.UIAxesLeftPyContour, 'gray');
               hold(app.UIAxesLeftPyContour, 'on');
               if ~isempty(mdatL)
                   viscircles(app.UIAxesLeftPyContour, centers_L, mdatL(3), 'EdgeColor', 'r');
                   plot(app.UIAxesLeftPyContour, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesLeftPyContour, 'off');
               
               imagesc(app.UIAxesRightPyContour, app.imageRight, [0, 255]); 
               colormap(app.UIAxesRightPyContour, 'gray');
               hold(app.UIAxesRightPyContour, 'on');
               if ~isempty(mdatR)
                   viscircles(app.UIAxesRightPyContour, centers_R, mdatR(3), 'EdgeColor', 'r');
                   plot(app.UIAxesRightPyContour, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
               hold(app.UIAxesRightPyContour, 'off');

           elseif (app.courtCov == 1)
               if ~isempty(mdatL) && ~isempty(mdatR)
                  plot(app.UIAxesLeftPyContour, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
                  plot(app.UIAxesRightPyContour, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
               end
           end


          % 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if ~isnan(xLeft) && ~isnan(xRight)
              % Baseline, focal length, and pixel size
              b = app.baseline * 1000;    % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = app.width / 2;  
              cyLeft = app.height/ 2;  
              cxRight = app.width / 2;  
              cyRight = app.height / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (-1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = app.camZ - Z;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITableCalcPyCon.Data = T;
              scatter(app.UIAxes_presX, app.sampledServeCounter,(X - app.x_pres),5, "green");
              hold(app.UIAxes_presX, "on");
              scatter(app.UIAxes_presY, app.sampledServeCounter,(Y - app.y_pres),5, "green");
              hold(app.UIAxes_presY, "on");
              scatter(app.UIAxes_presZ, app.sampledServeCounter,(Z1 - app.z_pres),5, "green");
              hold(app.UIAxesZError1, "on");
              %if (app.courtCov == 1)
              %   app.isDetectedFlag = 1;
              %   if (app.isDetectedPrev == 0)
              %       disp("Ball found at " + app.x_pres + " " + app.y_pres + " " + app.z_pres)
              %   end
              %   app.covX = app.x_pres;
              %   app.covY = app.y_pres;
              %   app.covZ = app.z_pres;
              %end
      
          else
             %if (app.courtCov == 1)
             %   app.isDetectedFlag = 0;
             %   if (app.isDetectedPrev == 1)
             %       disp("Ball lost, last seen at " + app.covX + " " + app.covY + " " + app.covZ)
             %   end
             %end
          end
       end


        %algorithm_lite implements the grayscale-sobel-circles algorithm in
        %Python. Algorithm code provided by Toby.
        function algorithm_implementation_cover(app)

           % Dr. Kaputa
           % Virtual Camera Demo
           % must run matlabStereoServer.py first on the FPGA SoC
           
           disp("Hello");
           %Initialization Parameters
           server_ip   = '129.21.94.18';     % IP address of the server
           server_port = 9999;                % Server Port of the sever
           
           client = tcpclient(server_ip,server_port);
           fprintf(1,"Connected to server\n");

           for cc = 1:1:265
              
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              % send raw frames
              %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

              pres_start = ((((2*cc)-2)*app.width)+1);
              pres_end = (((2*cc)-1) * app.width);
              rec_img_L = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
              imagesc(app.UIAxesRightLite, rec_img_L);
 
              pres_start = ((((2*cc)-1)*app.width)+1);
              pres_end = ((2*cc) * app.width);
              rec_img_R = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
              imagesc(app.UIAxesLeftLite, rec_img_R);

              for x = 1:2
                 write(client,'0');
                 flush(client);
                 dataL = rec_img_L;
                 dataR = rec_img_R;
                 dataL = uint8(dataL);
                 dataR = uint8(dataR);
                 
                 % mark the image number on the image
                 dataL = insertText(dataL,[100 100],x);
                 
                 dataLGray = im2gray(dataL);
                 dataRGray = im2gray(dataR);
                 imageStack = uint8(ones(app.height,app.width,8));
                 imageStack(:,:,1:3) = dataL;
                 imageStack(:,:,5:7) = dataR;
                 imageStack(:,:,4) = dataLGray;
                 imageStack(:,:,8) = dataRGray;
                 imageStack = permute(imageStack,[3 2 1]);
                 write(client,imageStack(:));
                 tic
                 temp = read(client,1);
                 toc
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 % receive processed frames
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
                 if x == 1
                     % receive feedthrough frame
                     write(client,'1');
                     flush(client);
                 else
                     % receive processed frame
                     write(client,'2');
                     flush(client); 
                 end
                 dataLeft = read(client,app.width*app.height);   
                 temp = reshape(dataLeft,[app.width,app.height]);
                 leftProcessed = permute(temp,[2 1]);
                 dataRight = read(client,app.width*app.height);
                 temp = reshape(dataRight,[app.width,app.height]);
                 rightProcessed = permute(temp,[2 1]);
                 imagesc(app.UIAxesLeftPySobel,leftProcessed);
                 imagesc(app.UIAxesRightPySobel,rightProcessed);
                 pause(1)
              end
               
              %TODO Replace with algorithm call
              %mdatL = pyrunfile("ball_detect_sobel.py --image blender_L_im_.jpg", "center_rad");
              %mdatR = pyrunfile("ball_detect_sobel.py --image blender_R_im_.jpg", "center_rad");
   
              %Conversion from py arrays to Matlab arrays
              
           end
        end

 %algorithm_lite implements the grayscale-sobel-circles algorithm in
        %Python. Algorithm code provided by Toby.
        function algorithm_implementation_serve(app)

           % Dr. Kaputa
           % Virtual Camera Demo
           % must run matlabStereoServer.py first on the FPGA SoC
           
           disp("Hello");
           %Initialization Parameters
           server_ip   = '129.21.94.18';     % IP address of the server
           server_port = 9999;                % Server Port of the sever

           app.sampledServeCounter = 1;
           
           client = tcpclient(server_ip,server_port);
           fprintf(1,"Connected to server\n");

           for counter = 1:app.framesTimeMs:app.numFrames 
               %Move the Tennis Ball. Due to how the files are formated, x
               %and y are swapped. See the professor's original code on myCourses for

               app.all_img_red = [app.all_img_red app.imageRight(:,:,1) app.imageLeft(:,:,1)];
               app.all_img_grn = [app.all_img_grn app.imageRight(:,:,2) app.imageLeft(:,:,2)];
               app.all_img_blu = [app.all_img_blu app.imageRight(:,:,3) app.imageLeft(:,:,3)];

               if app.sampledServeCounter == 1
                  rec_img = cat(3, app.all_img_red(:,1:752), app.all_img_grn(:,1:752), app.all_img_blu(:,1:752));
                  imagesc(app.UIAxesRightLite, rec_img);

                  rec_img = cat(3, app.all_img_red(:,753:1504), app.all_img_grn(:,753:1504), app.all_img_blu(:,753:1504));
                  imagesc(app.UIAxesLeftLite, rec_img);
               else
                  pres_start = ((((2*app.sampledServeCounter)-2)*app.width)+1);
                  pres_end = (((2*app.sampledServeCounter)-1) * app.width);
                  rec_img = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
                  imagesc(app.UIAxesRightLite, rec_img);
   
                  pres_start = ((((2*app.sampledServeCounter)-1)*app.width)+1);
                  pres_end = ((2*app.sampledServeCounter) * app.width);
                  rec_img = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
                  imagesc(app.UIAxesLeftLite, rec_img);
               end

               pres_start = ((((2*app.sampledServeCounter)-2)*app.width)+1);
               pres_end = (((2*app.sampledServeCounter)-1) * app.width);
               rec_img_L = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
               imagesc(app.UIAxesRightLite, rec_img_L);
  
               pres_start = ((((2*app.sampledServeCounter)-1)*app.width)+1);
               pres_end = ((2*app.sampledServeCounter) * app.width);
               rec_img_R = cat(3, app.all_img_red(:,pres_start:pres_end), app.all_img_grn(:,pres_start:pres_end), app.all_img_blu(:,pres_start:pres_end));
               imagesc(app.UIAxesLeftLite, rec_img_R);
 
               for x = 1:2
                  write(client,'0');
                  flush(client);
                  dataL = rec_img_L;
                  dataR = rec_img_R;
                  dataL = uint8(dataL);
                  dataR = uint8(dataR);
                  
                  % mark the image number on the image
                  dataL = insertText(dataL,[100 100],x);
                  
                  dataLGray = im2gray(dataL);
                  dataRGray = im2gray(dataR);
                  imageStack = uint8(ones(app.height,app.width,8));
                  imageStack(:,:,1:3) = dataL;
                  imageStack(:,:,5:7) = dataR;
                  imageStack(:,:,4) = dataLGray;
                  imageStack(:,:,8) = dataRGray;
                  imageStack = permute(imageStack,[3 2 1]);
                  write(client,imageStack(:));
                  tic
                  temp = read(client,1);
                  toc
                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                  % receive processed frames
                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
                  if x == 1
                      % receive feedthrough frame
                      write(client,'1');
                      flush(client);
                  else
                      % receive processed frame
                      write(client,'2');
                      flush(client); 
                  end
                  dataLeft = read(client,app.width*app.height);   
                  temp = reshape(dataLeft,[app.width,app.height]);
                  leftProcessed = permute(temp,[2 1]);
                  dataRight = read(client,app.width*app.height);
                  temp = reshape(dataRight,[app.width,app.height]);
                  rightProcessed = permute(temp,[2 1]);
                  imagesc(app.UIAxesLeftPySobel,leftProcessed);
                  imagesc(app.UIAxesRightPySobel,rightProcessed);
                  pause(1)
               end
               app.sampledServeCounter = app.sampledServeCounter + 1;
                
               %TODO Replace with algorithm call
               %mdatL = pyrunfile("ball_detect_sobel.py --image blender_L_im_.jpg", "center_rad");
               %mdatR = pyrunfile("ball_detect_sobel.py --image blender_R_im_.jpg", "center_rad");
    
               %Conversion from py arrays to Matlab arrays
              
           end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initializes parameters to app design values
            app.fileType = app.DataTypeDropDown.Value;
            app.width = app.CameraRespxSpinner.Value;
            app.serve_num = app.DataFileSpinner.Value;
            app.camX = app.camXSpinner.Value;
            app.camY = app.camYSpinner.Value;
            app.camZ = app.camZSpinner.Value;
            app.baseline = app.CameraBaseline.Value;
            app.showImg = app.HideImagesButton.Value;
            app.isDetectedFlag = 0;
            app.rangeOne = app.mRangeButton.Value;
            app.repeatFlag = 0;


        end

        % Value changed function: CameraRespxSpinner
        function CameraRespxSpinnerValueChanged(app, event)
           app.width = app.CameraRespxSpinner.Value;
        end

        % Value changed function: DataFileSpinner
        function DataFileSpinnerValueChanged(app, event)
            app.serve_num = app.DataFileSpinner.Value;
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            app.fileType = app.DataTypeDropDown.Value;
            
        end

        % Button pushed function: RunSelectionButton
        function RunSelectionButtonPushed(app, event)
            %Sets Lamp to indicate status of simulation
            app.CompleteLamp.Color = [1.00,0.00,0.00];
            serve_run(app);
            app.CompleteLamp.Color = [0.00,1.00,0.00];
        end

        % Value changed function: CameraBaseline
        function CameraBaselineValueChanged(app, event)
            app.baseline = app.CameraBaseline.Value;
            
        end

        % Value changed function: camXSpinner
        function camXSpinnerValueChanged(app, event)
            app.camX = app.camXSpinner.Value;
            
        end

        % Value changed function: camYSpinner
        function camYSpinnerValueChanged(app, event)
            app.camY = app.camYSpinner.Value;
            
        end

        % Value changed function: camZSpinner
        function camZSpinnerValueChanged(app, event)
            app.camZ = app.camZSpinner.Value;
            
        end

        % Value changed function: HideImagesButton
        function HideImagesButtonValueChanged(app, event)
            app.showImg = app.HideImagesButton.Value;
            
        end

        % Button pushed function: RunCoverageTest
        function RunCoverageTestButtonPushed(app, event)
            app.CompleteLamp.Color = [1.00,0.00,0.00];
            court_coverage_test(app);
            app.CompleteLamp.Color = [0.00,1.00,0.00];
        end

        % Value changed function: mRangeButton
        function mRangeButtonValueChanged(app, event)
            app.rangeOne = app.mRangeButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesRightLite = uiaxes(app.UIFigure);
            title(app.UIAxesRightLite, 'Right Matlab Sobel')
            xlabel(app.UIAxesRightLite, 'X')
            ylabel(app.UIAxesRightLite, 'Y')
            zlabel(app.UIAxesRightLite, 'Z')
            app.UIAxesLeftPySobel = uiaxes(app.UIFigure);
            title(app.UIAxesLeftPySobel, 'Left Python Sobel')
            xlabel(app.UIAxesLeftPySobel, 'X')
            ylabel(app.UIAxesLeftPySobel, 'Y')
            zlabel(app.UIAxesLeftPySobel, 'Z')
            app.UIAxesRightPySobel = uiaxes(app.UIFigure);
            title(app.UIAxesRightPySobel, 'Right Python Sobel')
            xlabel(app.UIAxesRightPySobel, 'X')
            ylabel(app.UIAxesRightPySobel, 'Y')
            zlabel(app.UIAxesRightPySobel, 'Z')
            app.UIAxesLeftOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesLeftOriginal, 'Left  Blender Image')
            xlabel(app.UIAxesLeftOriginal, 'X')
            ylabel(app.UIAxesLeftOriginal, 'Y')
            zlabel(app.UIAxesLeftOriginal, 'Z')
            app.UIAxesRightOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesRightOriginal, 'Right Blender Image')
            xlabel(app.UIAxesRightOriginal, 'X')
            ylabel(app.UIAxesRightOriginal, 'Y')
            zlabel(app.UIAxesRightOriginal, 'Z')
            app.UIAxesLeftPyContour = uiaxes(app.UIFigure);
            title(app.UIAxesLeftPyContour, 'Left Python Contour')
            xlabel(app.UIAxesLeftPyContour, 'X')
            ylabel(app.UIAxesLeftPyContour, 'Y')
            zlabel(app.UIAxesLeftPyContour, 'Z')
            app.UIAxesRightPyContour = uiaxes(app.UIFigure);
            title(app.UIAxesRightPyContour, 'Right Python Contour')
            xlabel(app.UIAxesRightPyContour, 'X')
            ylabel(app.UIAxesRightPyContour, 'Y')
            zlabel(app.UIAxesRightPyContour, 'Z')
            app.UIAxesXError1 = uiaxes(app.UIFigure);
            title(app.UIAxesXError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesXError1, 'Image')
            ylabel(app.UIAxesXError1, 'Error (m)')
            zlabel(app.UIAxesXError1, 'Z')
            app.UIAxesXError4 = uiaxes(app.UIFigure);
            title(app.UIAxesXError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesXError4, 'Image')
            ylabel(app.UIAxesXError4, 'Error (m)')
            zlabel(app.UIAxesXError4, 'Z')
            app.UIAxesXError5 = uiaxes(app.UIFigure);
            title(app.UIAxesXError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesXError5, 'Image')
            ylabel(app.UIAxesXError5, 'Error (m)')
            zlabel(app.UIAxesXError5, 'Z')
            app.UIAxesXError2 = uiaxes(app.UIFigure);
            title(app.UIAxesXError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesXError2, 'Image')
            ylabel(app.UIAxesXError2, 'Error (m)')
            zlabel(app.UIAxesXError2, 'Z')
            app.UIAxesXError3 = uiaxes(app.UIFigure);
            title(app.UIAxesXError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesXError3, 'Image')
            ylabel(app.UIAxesXError3, 'Error (m)')
            zlabel(app.UIAxesXError3, 'Z')
            app.UIAxesLeftLite = uiaxes(app.UIFigure);
            title(app.UIAxesLeftLite, 'Left Matlab Sobel')
            xlabel(app.UIAxesLeftLite, 'X')
            ylabel(app.UIAxesLeftLite, 'Y')
            zlabel(app.UIAxesLeftLite, 'Z')
            app.UIAxesYError1 = uiaxes(app.UIFigure);
            title(app.UIAxesYError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesYError1, 'Image')
            ylabel(app.UIAxesYError1, 'Error (m)')
            zlabel(app.UIAxesYError1, 'Z')
            app.UIAxesYError4 = uiaxes(app.UIFigure);
            title(app.UIAxesYError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesYError4, 'Image')
            ylabel(app.UIAxesYError4, 'Error (m)')
            zlabel(app.UIAxesYError4, 'Z')
            app.UIAxesYError5 = uiaxes(app.UIFigure);
            title(app.UIAxesYError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesYError5, 'Image')
            ylabel(app.UIAxesYError5, 'Error (m)')
            zlabel(app.UIAxesYError5, 'Z')
            app.UIAxesYError2 = uiaxes(app.UIFigure);
            title(app.UIAxesYError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesYError2, 'Image')
            ylabel(app.UIAxesYError2, 'Error (m)')
            zlabel(app.UIAxesYError2, 'Z')
            app.UIAxesYError3 = uiaxes(app.UIFigure);
            title(app.UIAxesYError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesYError3, 'Image')
            ylabel(app.UIAxesYError3, 'Error (m)')
            zlabel(app.UIAxesYError3, 'Z')
            app.UIAxesZError1 = uiaxes(app.UIFigure);
            title(app.UIAxesZError1, 'Error Comparison Data 1')
            xlabel(app.UIAxesZError1, 'Image')
            ylabel(app.UIAxesZError1, 'Error (m)')
            zlabel(app.UIAxesZError1, 'Z')
            app.UIAxesZError4 = uiaxes(app.UIFigure);
            title(app.UIAxesZError4, 'Error Comparison Data 4')
            xlabel(app.UIAxesZError4, 'Image')
            ylabel(app.UIAxesZError4, 'Error (m)')
            zlabel(app.UIAxesZError4, 'Z')
            app.UIAxesZError5 = uiaxes(app.UIFigure);
            title(app.UIAxesZError5, 'Error Comparison Data 5')
            xlabel(app.UIAxesZError5, 'Image')
            ylabel(app.UIAxesZError5, 'Error (m)')
            zlabel(app.UIAxesZError5, 'Z')
            app.UIAxesZError2 = uiaxes(app.UIFigure);
            title(app.UIAxesZError2, 'Error Comparison Data 2')
            xlabel(app.UIAxesZError2, 'Image')
            ylabel(app.UIAxesZError2, 'Error (m)')
            zlabel(app.UIAxesZError2, 'Z')
            app.UIAxesZError3 = uiaxes(app.UIFigure);
            title(app.UIAxesZError3, 'Error Comparison Data 3')
            xlabel(app.UIAxesZError3, 'Image')
            ylabel(app.UIAxesZError3, 'Error (m)')
            zlabel(app.UIAxesZError3, 'Z')
            app.UITableLeftPySobel = uitable(app.UIFigure);
            app.UITableLeftPySobel.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftPySobel.RowName = {};
            app.UITableRightPySobel = uitable(app.UIFigure);
            app.UITableRightPySobel.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightPySobel.RowName = {};
            app.UITablePySobel = uitable(app.UIFigure);
            app.UITablePySobel.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITablePySobel.RowName = {};
            app.UITableLeftLite = uitable(app.UIFigure);
            app.UITableLeftLite.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftLite.RowName = {};
            app.UITableRightLite = uitable(app.UIFigure);
            app.UITableRightLite.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightLite.RowName = {};
            app.UITableClacLite = uitable(app.UIFigure);
            app.UITableClacLite.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacLite.RowName = {};
            app.UITableExpected = uitable(app.UIFigure);
            app.UITableExpected.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableExpected.RowName = {};
            app.CameraRespxSpinnerLabel = uilabel(app.UIFigure);
            app.CameraRespxSpinnerLabel.Text = 'Camera Res (px)';
            app.CameraRespxSpinner = uispinner(app.UIFigure);
            app.CameraRespxSpinner.Step = 1168;
            app.CameraRespxSpinner.Limits = [752 1920];
            app.CameraRespxSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraRespxSpinnerValueChanged, true);
            app.CameraRespxSpinner.Value = 752;
            app.DataFileSpinnerLabel = uilabel(app.UIFigure);
            app.DataFileSpinnerLabel.Text = 'Data File';
            app.DataFileSpinner = uispinner(app.UIFigure);
            app.DataFileSpinner.Limits = [1 5];
            app.DataFileSpinner.ValueChangedFcn = createCallbackFcn(app, @DataFileSpinnerValueChanged, true);
            app.DataFileSpinner.Value = 1;
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'serve', 'volley'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'serve';
            app.UITableLeftPyCon = uitable(app.UIFigure);
            app.UITableLeftPyCon.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftPyCon.RowName = {};
            app.UITableRightPyCon = uitable(app.UIFigure);
            app.UITableRightPyCon.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightPyCon.RowName = {};
            app.UITableCalcPyCon = uitable(app.UIFigure);
            app.UITableCalcPyCon.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableCalcPyCon.RowName = {};
            app.ActualPositionfromBlenderLabel = uilabel(app.UIFigure);
            app.ActualPositionfromBlenderLabel.Text = 'Actual Position from Blender';
            app.MatlabSobelCircleFilterResultsLabel = uilabel(app.UIFigure);
            app.MatlabSobelCircleFilterResultsLabel.Text = 'Matlab Sobel Circle Filter Results';
            app.PythonSobelCircleFilterResultsLabel = uilabel(app.UIFigure);
            app.PythonSobelCircleFilterResultsLabel.Text = 'Python Sobel Circle Filter Results';
            app.PythonGreenContourResultsLabel = uilabel(app.UIFigure);
            app.PythonGreenContourResultsLabel.Text = 'Python Green Contour Results';
            app.AquaTrackAlgorithmBulkTestCaseAppLabel = uilabel(app.UIFigure);
            app.AquaTrackAlgorithmBulkTestCaseAppLabel.Text = 'AquaTrack Algorithm Bulk Test Case App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Sprite-0001.png');
            app.RunSelectionButton = uibutton(app.UIFigure, 'push');
            app.RunSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunSelectionButtonPushed, true);
            app.RunSelectionButton.Text = 'Run Selection';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.CompleteLampLabel = uilabel(app.UIFigure);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.UIFigure);
            app.XmSpinnerLabel = uilabel(app.UIFigure);
            app.XmSpinnerLabel.Text = 'X (m)';
            app.camXSpinner = uispinner(app.UIFigure);
            app.camXSpinner.Step = 0.01;
            app.camXSpinner.Limits = [0 100];
            app.camXSpinner.ValueChangedFcn = createCallbackFcn(app, @camXSpinnerValueChanged, true);
            app.YmSpinnerLabel = uilabel(app.UIFigure);
            app.YmSpinnerLabel.Text = 'Y (m)';
            app.camYSpinner = uispinner(app.UIFigure);
            app.camYSpinner.Step = 0.01;
            app.camYSpinner.Limits = [0 100];
            app.camYSpinner.ValueChangedFcn = createCallbackFcn(app, @camYSpinnerValueChanged, true);
            app.ZmSpinnerLabel = uilabel(app.UIFigure);
            app.ZmSpinnerLabel.Text = 'Z (m)';
            app.camZSpinner = uispinner(app.UIFigure);
            app.camZSpinner.Step = 0.01;
            app.camZSpinner.Limits = [0 100];
            app.camZSpinner.ValueChangedFcn = createCallbackFcn(app, @camZSpinnerValueChanged, true);
            app.camZSpinner.Value = 11;
            app.CameraLocationLabel = uilabel(app.UIFigure);
            app.CameraLocationLabel.Text = {'Camera'; 'Location'};
            app.CameraRespxSpinnerLabel_2 = uilabel(app.UIFigure);
            app.CameraRespxSpinnerLabel_2.Text = 'Camera Baseline (m)';
            app.CameraBaseline = uispinner(app.UIFigure);
            app.CameraBaseline.Step = 0.001;
            app.CameraBaseline.Limits = [0 100];
            app.CameraBaseline.ValueChangedFcn = createCallbackFcn(app, @CameraBaselineValueChanged, true);
            app.CameraBaseline.Value = 0.06;
            app.HideImagesButton = uibutton(app.UIFigure, 'state');
            app.HideImagesButton.ValueChangedFcn = createCallbackFcn(app, @HideImagesButtonValueChanged, true);
            app.HideImagesButton.Text = 'Hide Images';
            app.RunCoverageTest = uibutton(app.UIFigure, 'push');
            app.RunCoverageTest.ButtonPushedFcn = createCallbackFcn(app, @RunCoverageTestButtonPushed, true);
            app.RunCoverageTest.Text = 'Run Coverage Test';
            app.StepIncrementButtonGroup = uibuttongroup(app.UIFigure);
            app.StepIncrementButtonGroup.Title = 'Step Increment';
            app.Button = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button.Text = '0.01';
            app.Button_2 = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button_2.Text = '0.1';
            app.Button_3 = uitogglebutton(app.StepIncrementButtonGroup);
            app.Button_3.Text = '1';
            app.Button_3.Value = true;
            app.mRangeButton = uibutton(app.UIFigure, 'state');
            app.mRangeButton.ValueChangedFcn = createCallbackFcn(app, @mRangeButtonValueChanged, true);
            app.mRangeButton.Text = '5m Range';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Implementation_Verifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end